classdef VolumePointPicker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FileMenu               matlab.ui.container.Menu
        OpenImageMenu          matlab.ui.container.Menu
        OpenRecentMenu         matlab.ui.container.Menu
        ExportPointsMenu       matlab.ui.container.Menu
        LoadSessionMenu        matlab.ui.container.Menu
        LoadRecentSessionMenu  matlab.ui.container.Menu
        SaveSessionMenu        matlab.ui.container.Menu
        CloseAppMenu           matlab.ui.container.Menu
        ViewSettingsPanel      matlab.ui.container.Panel
        AutoMaxIntensityProjectionLabel  matlab.ui.control.Label
        MIPSwitch              matlab.ui.control.Switch
        ColormapDropDownLabel  matlab.ui.control.Label
        ColormapDropDown       matlab.ui.control.DropDown
        DVolumeSwitchLabel     matlab.ui.control.Label
        VolumeViewSwitch       matlab.ui.control.Switch
        AxisLabelsSwitchLabel  matlab.ui.control.Label
        AxisLabelsSwitch       matlab.ui.control.Switch
        PointsPanel            matlab.ui.container.Panel
        UndoButton             matlab.ui.control.Button
        RedoButton             matlab.ui.control.Button
        DeletePointButton      matlab.ui.control.Button
        UITable                matlab.ui.control.Table
        CopyButton             matlab.ui.control.Button
        EditButton             matlab.ui.control.StateButton
        StatusWindow           matlab.ui.control.TextArea
        ImagePanel             matlab.ui.container.Panel
        PickPointsButton       matlab.ui.control.Button
        FrameDropDown          matlab.ui.control.DropDown
        FwdImageButton         matlab.ui.control.Button
        BackImageButton        matlab.ui.control.Button
    end

    
    properties (Constant)
        axesOrder = {'xy', 'xz', 'yz'}
        editComponentList = {'UITable', 'MIPSwitch', ...
            'ColormapDropDown', 'VolumeViewSwitch', 'AxisLabelsSwitch', ...
            'AxisLabelsSwitchLabel', 'AutoMaxIntensityProjectionLabel', ...
            'ColormapDropDownLabel', 'DVolumeSwitchLabel'};
        viewComponentList = {'EditButton', 'CopyButton'};
    end
    
    properties (SetObservable)
        ColorWeight = [1 1 0]  
        imPath
        volImage % the volume image currently loaded and displayed in the GUI
    end
    
    properties (SetObservable, Access= private)
        pointStore
    end
    
    properties (Access = private)
        statusTimer = timer
        IVV
        SliceFig
        SliceAx
        SliceSurfaces
        SlicePlots
        sphere
    end
    
    properties (Dependent, Access = private)
        P
    end
    
    properties (Dependent)
        cmap
        crosshairGap
    end
    
    methods
        
        function [out] = get.sphere(app)
            if isempty(app.sphere)
                app.sphere = cell(1, 3);
                [app.sphere{:}] = sphere(5);
                app.sphere = cellfun(@(x) x * app.crosshairGap, app.sphere, ...
                    'UniformOutput', false);
            end
            out = app.sphere;
        end
        
        function out = get.crosshairGap(app)
           out = min(size(app.volImage)) * 0.05;
        end
        
        function out = get.cmap(app)
           out = app.ColormapDropDown.Value;
        end
        
        function onIVVClose(app)
           app.IVV = [];
           app.PickPointsButton.Enable = 'on';
           app.disableComponents(app.editComponentList);
           figure(app.UIFigure);
        end
        
        function addPoint(app, ind)
           if isempty(app.pointStore)
              app.pointStore = {};
           end
           app.pointStore = [app.pointStore; [{false}, num2cell(ind([2, 1, 3]))]];
        end
        
    end
    
    methods (Access = private)
        
        function doForViews(app, fun, varargin)
            for i = 1:app.nViews
                getProp = @(x) app.([app.axesOrder{i}, x]);
                args = cellfun(getProp, varargin, 'UniformOutput', false);
                fun(args{:});
            end
        end
        
        function loadImage(app)
            warning('NOT IMPLEMENTED: loading of.mat files');
            app.volImage = im2double(utils.volread(app.imPath));
        end
        
        function listStatus(app, msg, flag, duration)
            stop(app.statusTimer);
            if nargin < 2, msg = ''; end
            if nargin < 3, flag = ''; end
            if nargin < 4, duration = []; end
            
            app.StatusWindow.Value = msg;
            switch flag
                case 'good'
                    c = [47 67 19] / 100;
                case 'warn'
                    c = [85 33 10] / 100;
                case 'error'
                    c = [100 20 20] / 100;
                otherwise
                    c = [1 1 1] * 0.85;
            end
            app.StatusWindow.FontColor = c;
            
            if ~isempty(duration)
                app.statusTimer = timer('TimerFcn', @(~, ~) app.listStatus, ...
                    'StartDelay', duration);
                start(app.statusTimer);
            end
        end
        
        function confirmAppClose(app, fcn)
            if isempty(app.volImage)
                fcn();
            else
                title = 'Confirm Close';
                msg = 'Are you sure you want to close VPP?';
                fh = app.UIFigure;
                opts = {'Yes', 'Cancel'};
                icon = 'question';
                callback = @uiconfirmCallback;
                uiconfirm(fh, msg, title, ...
                    'Options', opts, ...
                    'Icon', icon, ...
                    'DefaultOption', 1, ...
                    'CancelOption', 2, ...
                    'CloseFcn', callback);
            end
            
            function uiconfirmCallback(~, event)
                if event.SelectedOptionIndex == 1 % close
                    fcn();
                end
            end
        end
        
        function confirmImageOpen(app, fcn)
            if isempty(app.volImage)
                fcn();
            else
                title = 'Confirm Open';
                msg =  ['Are you sure you want to open a new image? This will close the ', ...
                    'current VPP session.'];
                fh = app.UIFigure;
                opts =  {'Open Anyway', 'Cancel'};
                icon = 'warning';
                callback = @uiconfirmCallback;
                uiconfirm(fh, msg, title, ...
                    'Options', opts, ...
                    'Icon', icon, ...
                    'DefaultOption', 1, ...
                    'CancelOption', 2, ...
                    'CloseFcn', callback);
            end
            
            function uiconfirmCallback(~, event)
                if event.SelectedOptionIndex == 1 % open anyway
                    if ~isempty(app.IVV)
                        close(app.IVV);
                    end
                    fcn();
                end
            end
        end
        
        function enableComponents(app, list)
            for i = 1:length(list)
                app.(list{i}).Enable = 'on';
            end
        end
        
        function disableComponents(app, list)
            for i = 1:length(list)
                app.(list{i}).Enable = 'off';
            end
        end
        
        function openIVV(app)
            app.enableComponents(app.editComponentList);
            app.IVV = IVV(app);
            app.IVV.FileName = app.imPath;
            app.IVV.figure;
        end
        
        function updatePointTable(app)
            app.UITable.Data = app.pointStore;
        end
        
        function openSliceFig(app)
            [~, fName] = fileparts(app.imPath);
            app.SliceFig = figure('Name', ['VPP - 3D Slice Viewer - ', fName], 'Position', ...
                [app.UIFigure.Position(1) + app.UIFigure.Position(3) + 600, ...
                app.UIFigure.Position(1), 600, app.UIFigure.Position(4)], 'MenuBar', 'none' , ...
                'IntegerHandle', 'off', 'Color', app.UIFigure.Color);
            app.SliceAx = gca;
            hold(app.SliceAx, 'on');
            grid(app.SliceAx, 'on');
            app.SliceAx.Visible = 'on';
            [app.SliceAx.XColor, app.SliceAx.YColor, app.SliceAx.ZColor] ...
                = deal([0.85 0.85 0.85]);
            app.SliceAx.Color = [0.6 0.6 0.6];
            app.SliceAx.Box = 'on';
            app.SliceAx.XLabel.String = 'X';
            app.SliceAx.YLabel.String = 'Y';
            app.SliceAx.ZLabel.String = 'Z';
            view(app.SliceAx, [45 30]);
            app.SliceAx.GridColor = [0.85 0.85 0.85];
            app.SliceFig.WindowButtonMotionFcn = @app.onSliceFigMouseMove;
            app.SliceFig.DeleteFcn = @app.onSliceFigClose;
            
            app.SlicePlots = gobjects(1, 3);
            for i = 1:3
                app.SlicePlots(i) = plot3(app.SliceAx, 1, 1, 1, 'r', 'LineWidth', 2);
            end
            [sx, sy, sz] = app.sphere{:};
            app.SliceSurfaces = surf(sx, sy, sz, 'EdgeColor', 'none', 'FaceColor', [0.9 0.9 0.9]);
            app.sliceFigRefresh;
            
            [f, v, c] =  isosurface(double(app.volImage > 0.1), 0.5, app.volImage);
            p = patch(app.SliceAx, 'Faces', f, 'Vertices', v, ...
                'FaceAlpha', 0.6, 'EdgeColor', 'none', 'FaceColor', 'b');
            camlight(app.SliceAx);
            lighting(app.SliceAx, 'gouraud');
            
            rotate3d(app.SliceFig, 'on');
            colormap(app.SliceAx, app.cmap);
            sz = size(app.volImage);
            app.SliceAx.XLim = [0.5, sz(2) + 0.5];
            app.SliceAx.YLim = [0.5, sz(1) + 0.5];
            app.SliceAx.ZLim = [0.5, sz(3) + 0.5];
            [app.SliceAx.XLimMode, app.SliceAx.YLimMode, app.SliceAx.ZLimMode] ...
                = deal('manual');
            drawnow('limitrate');
        end
        
        function sliceFigRefresh(app)
            if isempty(app.SliceFig) || ~isvalid(app.SliceFig), return; end
            %          points = cellfun(@(s) round(app.P.(s)), {'x', 'y', 'z'}, 'UniformOutput', false);
            %           app.SliceSurfaces = slice(app.SliceAx, app.volImage, points{:}, 'nearest');
            %           [app.SliceSurfaces(:).EdgeColor] = deal('none');
            [sx, sy, sz] = app.sphere{:};
            app.SliceSurfaces.XData = sx + app.P.x;
            app.SliceSurfaces.YData = sy + app.P.y;
            app.SliceSurfaces.ZData = sz + app.P.z;
            
            [app.SlicePlots(:).XData] = deal([1 1] * app.P.x);
            [app.SlicePlots(:).YData] = deal([1 1] * app.P.y);
            [app.SlicePlots(:).ZData] = deal([1 1] * app.P.z);
            
            % xy
            app.SlicePlots(1).ZData = [0.5 size(app.volImage, 3) + 0.5];
            % xz
            app.SlicePlots(2).YData = [0.5 size(app.volImage, 1) + 0.5];
            % yz
            app.SlicePlots(3).XData = [0.5 size(app.volImage, 2) + 0.5];
            
            if app.P.xLock < 2
                app.SlicePlots(3).Color = 'g';
            else
                app.SlicePlots(3).Color = 'r';
            end
            
            if app.P.yLock < 2
                app.SlicePlots(2).Color = 'g';
            else
                app.SlicePlots(2).Color = 'r';
            end
            
            if app.P.zLock < 2
                app.SlicePlots(1).Color = 'g';
            else
                app.SlicePlots(1).Color = 'r';
            end
        end
        
        function onSliceFigMouseMove(app, src, event)
            app.listStatus('Rotate the volume by clicking and dragging.', '', 5);
            app.SliceFig.WindowButtonMotionFcn = [];
        end
        
        function onSliceFigClose(app, src, event)
            disp('Closing Figure');
            app.VolumeViewSwitch.Value = false;
            figure(app.UIFigure);
        end
        
        
        function handlePropEvents(app, src, ~)
            switch src.Name
                case 'volImage'
                    if isempty(app.volImage) && ~isempty(app.imPath)
                        app.imPath = [];
                    else
                        app.openIVV;
                    end
                    
                case 'imPath'
                    if isempty(app.imPath) && ~isempty(app.volImage)
                        app.volImage = [];
                    else
                        app.loadImage;
                    end
                    
                case 'pointStore'
                    app.updatePointTable;
                    
            end
        end
        
    end
    
    methods (Static)
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, imPath)
            L = utils.Logger('volumePointPicker.startupFcn');
            L.info('Beginnging VPP');
            app.addlistener('volImage','PostSet',@app.handlePropEvents);
            app.addlistener('imPath','PostSet',@app.handlePropEvents);
            app.addlistener('pointStore', 'PostSet', @app.handlePropEvents);
            app.UITable.RowName = 'numbered';
            app.VolumeViewSwitch.ItemsData = [false, true];
            switch nargin
                case 2
                    app.imPath = imPath;
            end
        end

        % Button pushed function: PickPointsButton
        function PickPointsButtonPushed(app, event)
            app.openIVV;
            app.PickPointsButton.Enable = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.confirmAppClose(@closeFun);
            function closeFun
                if ~isempty(app.IVV)
                    app.IVV.close;
                end
                delete(app)
            end
        end

        % Menu selected function: OpenImageMenu
        function OpenImageMenuSelected(app, event)
            app.confirmImageOpen(@openFromUI);
            function openFromUI
                [file, path] = uigetfile({
                    '*.tif', 'TIF File'
                    '*.mat', 'MATLAB Data File'
                    '*.*', 'Any file types'}, ...
                    'Select an volume image', ...
                    '../data/phantoms/axis_phantom_256.tif');
                if file
                    app.imPath = fullfile(path, file);
                end
            end
        end

        % Menu selected function: CloseAppMenu
        function CloseAppMenuSelected(app, event)
            app.UIFigureCloseRequest(event);
        end

        % Value changed function: EditButton
        function EditButtonValueChanged(app, event)
            value = app.EditButton.Value;
            if value
                app.EditButton.BackgroundColor = [.98 0.69 0.13];
                app.EditButton.Text = 'Done';
                app.DeletePointButton.Enable = 'on';
                app.UITable.ColumnEditable = true;
            else
                app.EditButton.BackgroundColor = [1 1 1] * 0.94;
                app.EditButton.Text = 'Edit';
                app.DeletePointButton.Enable = 'off';
                app.UITable.ColumnEditable = false;
            end
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            axs = {'SliceAx', 'IVVAx'};
            for i = 1:length(axs)
                h = app.(axs{i});
                if ~isempty(h) && isvalid(h)
                    colormap(h, app.cmap);
                end
            end
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
            nPoints = size(app.pointStore, 1);
            if nPoints == 0
                app.listStatus('No points to copy.', 'warn', 5);
                return
            end
            lns = strings(1, nPoints);
            for i = 1:nPoints
                lns(i) = sprintf("%f\t%f\t%f", app.pointStore{i, 2:4});
            end
            clipboard('copy', char(sprintf(strjoin(lns, '\n'))));
            msg = sprintf('%d point coordinate coppied to the clipboard.', nPoints);
            if nPoints ~= 1
                msg = strrep(msg, 'coordinate', 'coordinates');
            end
            app.listStatus(msg, 'good', 5);
        end

        % Value changed function: VolumeViewSwitch
        function VolumeViewSwitchValueChanged(app, event)
            value = app.VolumeViewSwitch.Value;
            disp(value);
            if value
                app.openSliceFig
            else
                close(app.SliceFig)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Volume Point Picker (VPP)';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenImageMenu = uimenu(app.FileMenu);
            app.OpenImageMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenImageMenuSelected, true);
            app.OpenImageMenu.Text = 'Open Image ...';
            app.OpenRecentMenu = uimenu(app.FileMenu);
            app.OpenRecentMenu.Enable = 'off';
            app.OpenRecentMenu.Text = 'Open Recent';
            app.ExportPointsMenu = uimenu(app.FileMenu);
            app.ExportPointsMenu.Enable = 'off';
            app.ExportPointsMenu.Text = 'Export Points ...';
            app.LoadSessionMenu = uimenu(app.FileMenu);
            app.LoadSessionMenu.Enable = 'off';
            app.LoadSessionMenu.Separator = 'on';
            app.LoadSessionMenu.Text = 'Load Session ...';
            app.LoadRecentSessionMenu = uimenu(app.FileMenu);
            app.LoadRecentSessionMenu.Enable = 'off';
            app.LoadRecentSessionMenu.Text = 'Load Recent Session';
            app.SaveSessionMenu = uimenu(app.FileMenu);
            app.SaveSessionMenu.Enable = 'off';
            app.SaveSessionMenu.Text = 'Save Session ...';
            app.CloseAppMenu = uimenu(app.FileMenu);
            app.CloseAppMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseAppMenuSelected, true);
            app.CloseAppMenu.Separator = 'on';
            app.CloseAppMenu.Text = 'Close App';
            app.ViewSettingsPanel = uipanel(app.UIFigure);
            app.ViewSettingsPanel.Title = 'View Settings';
            app.AutoMaxIntensityProjectionLabel = uilabel(app.ViewSettingsPanel);
            app.AutoMaxIntensityProjectionLabel.Enable = 'off';
            app.AutoMaxIntensityProjectionLabel.Text = 'Auto Max Intensity Projection';
            app.MIPSwitch = uiswitch(app.ViewSettingsPanel, 'slider');
            app.MIPSwitch.Enable = 'off';
            app.MIPSwitch.Value = 'On';
            app.ColormapDropDownLabel = uilabel(app.ViewSettingsPanel);
            app.ColormapDropDownLabel.Enable = 'off';
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.ViewSettingsPanel);
            app.ColormapDropDown.Items = {'gray', 'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'flag'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Enable = 'off';
            app.ColormapDropDown.Value = 'gray';
            app.DVolumeSwitchLabel = uilabel(app.ViewSettingsPanel);
            app.DVolumeSwitchLabel.Enable = 'off';
            app.DVolumeSwitchLabel.Text = '3D Volume';
            app.VolumeViewSwitch = uiswitch(app.ViewSettingsPanel, 'slider');
            app.VolumeViewSwitch.ValueChangedFcn = createCallbackFcn(app, @VolumeViewSwitchValueChanged, true);
            app.VolumeViewSwitch.Enable = 'off';
            app.AxisLabelsSwitchLabel = uilabel(app.ViewSettingsPanel);
            app.AxisLabelsSwitchLabel.Enable = 'off';
            app.AxisLabelsSwitchLabel.Text = 'Axis Labels';
            app.AxisLabelsSwitch = uiswitch(app.ViewSettingsPanel, 'slider');
            app.AxisLabelsSwitch.Enable = 'off';
            app.AxisLabelsSwitch.Value = 'On';
            app.PointsPanel = uipanel(app.UIFigure);
            app.PointsPanel.Title = 'Points';
            app.UndoButton = uibutton(app.PointsPanel, 'push');
            app.UndoButton.Enable = 'off';
            app.UndoButton.Text = '';
            app.RedoButton = uibutton(app.PointsPanel, 'push');
            app.RedoButton.Enable = 'off';
            app.RedoButton.Text = '';
            app.DeletePointButton = uibutton(app.PointsPanel, 'push');
            app.DeletePointButton.Enable = 'off';
            app.DeletePointButton.Text = 'Delete ...';
            app.UITable = uitable(app.PointsPanel);
            app.UITable.ColumnName = {''; 'x'; 'y'; 'z'};
            app.UITable.RowName = {};
            app.UITable.Enable = 'off';
            app.CopyButton = uibutton(app.PointsPanel, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Enable = 'off';
            app.CopyButton.Text = 'Copy All';
            app.EditButton = uibutton(app.PointsPanel, 'state');
            app.EditButton.ValueChangedFcn = createCallbackFcn(app, @EditButtonValueChanged, true);
            app.EditButton.Enable = 'off';
            app.EditButton.Text = 'Edit';
            app.StatusWindow = uitextarea(app.UIFigure);
            app.StatusWindow.Editable = 'off';
            app.StatusWindow.Value = {'Click "File > Open..." to load an image.'};
            app.ImagePanel = uipanel(app.UIFigure);
            app.ImagePanel.Title = 'Image';
            app.PickPointsButton = uibutton(app.ImagePanel, 'push');
            app.PickPointsButton.ButtonPushedFcn = createCallbackFcn(app, @PickPointsButtonPushed, true);
            app.PickPointsButton.Enable = 'off';
            app.PickPointsButton.Text = 'Pick Points';
            app.FrameDropDown = uidropdown(app.ImagePanel);
            app.FrameDropDown.Items = {};
            app.FrameDropDown.Enable = 'off';
            app.FrameDropDown.Value = {};
            app.FwdImageButton = uibutton(app.ImagePanel, 'push');
            app.FwdImageButton.Enable = 'off';
            app.FwdImageButton.Text = '';
            app.BackImageButton = uibutton(app.ImagePanel, 'push');
            app.BackImageButton.Enable = 'off';
            app.BackImageButton.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = VolumePointPicker(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end